classdef MouseActivityGui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MortgageCalculatorUIFigure   matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        AnaylysisParametersPanel     matlab.ui.container.Panel
        MousenumberEditFieldLabel    matlab.ui.control.Label
        MousenumberEditField         matlab.ui.control.NumericEditField
        LastframeEditFieldLabel      matlab.ui.control.Label
        LastframeEditField           matlab.ui.control.NumericEditField
        StartframeEditFieldLabel     matlab.ui.control.Label
        StartframeEditField          matlab.ui.control.NumericEditField
        StepEditFieldLabel           matlab.ui.control.Label
        StepEditField                matlab.ui.control.NumericEditField
        RunButton                    matlab.ui.control.Button
        MinimalpixelsofobjLabel      matlab.ui.control.Label
        MinimalpixelsofobjEditField  matlab.ui.control.NumericEditField
        ThresholdSpinnerLabel        matlab.ui.control.Label
        ThresholdSpinner             matlab.ui.control.Spinner
        LoadVideoPanel               matlab.ui.container.Panel
        VideopathEditFieldLabel      matlab.ui.control.Label
        VideopathEditField           matlab.ui.control.EditField
        LoadButton                   matlab.ui.control.Button
        RightPanel                   matlab.ui.container.Panel
        UIAxes                       matlab.ui.control.UIAxes
        drawnextButton               matlab.ui.control.Button
        CheckresultsButton           matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        % file path
        filename = ''; 
        dirpath = '';
        
        % result vars
        result_name = '';
        resultpath = '';
        imgfilename = '';
        
        % video properties
        video_obj = '';
        video_name = '';
        nframes = 0;
        frame100 = '';
        videosize = 0;
        frameRate = 0;
        duration = 0;
        
        % threshold property
        threshold = 0.5;
        objpixels = 0;
        
        % analysis parameters
        mouseN = 0;
        StartFrame = 0;
        LastFrame = 0;
        Step = 0;
        
        % drawrect result
        arenaD = 0;
        xa = 0;
        ya = 0;
        arena_w = 0;
        arena_h = 0;
    end

    methods (Access = private)
        
        function video = openvideo(app, filepath)
            try
                video = VideoReader(filepath);
            catch exception
                close_handle(dialog);
                msgbox(['Error opening video file. Message: ' exception.message], ...
                    'Open video', 'error');
                
                set_status('Error when opening video file', 'button_cancel_red', true);
                return;
            end
            app.nframes = video.NumberOfFrames;
            app.frame100 = read(video,100);
            app.videosize = [video.Width,video.Height];
            app.frameRate = video.FrameRate;
            app.duration = video.Duration;
            %(~exist([app.dirpath app.video_name],'dir'))
            %    mkdir([app.dirpath app.video_name]);
            %end
            app.imgfilename = [app.dirpath app.video_name '/' app.video_name '_threshold'];
        end
        
        
        function [arenaD,xa,ya,arena_w,arena_h] = drawrect(app, StartFrame,MouseN,video_obj)
            figure('Position', [10 10 900 900]);
            imshow(read(video_obj,StartFrame));
            title(['please draw the arena for ', num2str(MouseN), 'mouses'])
            arenaD = cell(MouseN,1);
            xa = cell(MouseN,1);
            ya = cell(MouseN,1);
            arena_w = cell(MouseN,1);
            arena_h = cell(MouseN,1);
             
            for n = 1:MouseN
                arena = drawrectangle('Position', [20 20 254 254]);
                position = customWait(app, arena);
                %w = waitforbuttonpress;
                %position = rbbox;
                % annotation('rectangle',position,'Color','red',"Parent", f) 
                arenaD{n} = floor(position);
                xa{n} = arenaD{n}(1);
                ya{n} = arenaD{n}(2);
                arena_w{n} = arenaD{n}(3);
                arena_h{n} = arenaD{n}(4);
            end      
            close all
            app.arenaD = arenaD;
            app.arena_h = arena_h;
            app.arena_w = arena_w;
            app.xa = xa;
            app.ya = ya;
        end

        
        function clickCallback(~, evt)
            if strcmp(evt.SelectionType,'double')
                uiresume;
            end
          end
          
        
        function pos = customWait(app, hROI)
        
            % Listen for mouse clicks on the ROI
            %l = addlistener(hROI,'ROIClicked',@clickCallback);
            
            % Block program execution
            uiwait;
            
            % Remove listener
            %delete(l);
            
            % Return the current position
            pos = hROI.Position;
            
        end
        
        
        function result = init_result(app)
            result.filepath = app.filename;
            result.frameRate = app.frameRate;
            result.duration = app.duration;
            result.nframes = app.nframes;
            result.videosize = app.videosize;
            result.StartFrame = app.StartFrame;
            result.LastFrame = app.LastFrame;
            result.arenaD = app.arenaD;
            result.mouseN = app.mouseN;
            result.step = app.Step;
        end
    
        
        function [m1_majl,m1_minl,m1_ori,m1_ecc,m1_area] = collect_trackdata(app, nframes,StartFrame,LastFrame,Step,MouseN,threshold,objpixels, ...
                                                                                video_obj,xa,ya,arena_w,arena_h,resultpath,f,result)
            x = StartFrame:Step:LastFrame;
            
            c1 = cell(MouseN, length(x), 2);
            m1_majl = cell(MouseN, length(x), 1);
            m1_minl = cell(MouseN, length(x), 1);
            m1_ori = cell(MouseN, length(x), 1);
            m1_ecc = cell(MouseN, length(x), 1);
            m1_area = cell(MouseN, length(x), 1);
            f = waitbar(0, sprintf(['Tracking Progress: ' num2str(0) '%%']));
            for jj = 1:length(x)
                
                frame2 = bwareaopen(im2bw(255-read(video_obj,x(jj)), threshold),objpixels) * 255; % remove any object smaller than 50 pixels
                
                for m = 1:MouseN
                    %roi = frame2(ya{m}:ya{m}+arena_h{m}, xa{m}:xa{m}+arena_w{m});
                    mouse = regionprops(frame2(ya{m}:ya{m}+arena_h{m}, xa{m}:xa{m}+arena_w{m}),'Area','Centroid','MajorAxisLength','MinorAxisLength','Orientation','Eccentricity');
                    if ~isempty([mouse.Area])
                        areaArray = [mouse.Area];
                        [~,idx] = max(areaArray);
                        c1{m}(jj,:) = floor(mouse(idx).Centroid +[xa{m} ya{m}]);
                        
                        m1_majl{m}(jj,:) = mouse(idx).MajorAxisLength;
                        m1_minl{m}(jj,:) = mouse(idx).MinorAxisLength;
                        m1_ori{m}(jj,:) = mouse(idx).Orientation;
                        m1_ecc{m}(jj,:) = mouse(idx).Eccentricity;
                        m1_area{m}(jj,:) = mouse(idx).Area;
                    else
                        %the following is needed because mice sometimes jump
                        %up at the edge (out of the ROI).
                        c1{m}(jj,:) = c1{m}(jj-1,:);
                        m1_majl{m}(jj,:) = m1_majl{m}(jj-1);
                        m1_minl{m}(jj,:) = m1_minl{m}(jj-1);
                        m1_ori{m}(jj,:) = m1_ori{m}(jj-1);
                        m1_ecc{m}(jj,:) = m1_ecc{m}(jj-1);
                        m1_area{m}(jj,:) = m1_area{m}(jj-1);
                    end
                end
                %the following three lines greatly accelerate the read
                %videoframe process
                if Step>1
                    read(video_obj,[x(jj)+1 min(nframes,x(jj)+Step-1)]);
                end
                
                waitbar(jj/length(x), f, sprintf(['Tracking Progress: ' num2str(round(jj*10000/length(x))/100) '%%']))                                                            
            end
            close(f);
            result = init_result(app);
            result.positions = {};
            result.area = {};
            result.orientation = {};
            
            for p = 1:MouseN
                result.positions = [result.positions c1{p}];
                result.area = [result.area m1_area{p}];
                result.orientation = [result.orientation [m1_majl{p} m1_minl{p} m1_ori{p} m1_ecc{p}]];
            end
            save(resultpath, 'result');
        end
        
        
        function analysis(app)
            R = load(app.resultpath);
            logf(app, 'Data loaded')
            startframe = R.result.StartFrame;
            lastframe = R.result.LastFrame;
            step = R.result.step;
            totalframe = floor((lastframe-startframe)/step)+1;
            frameRate = R.result.frameRate;
            videosize = R.result.videosize;
            arena = R.result.arenaD;
            pos = R.result.positions;
            ori = R.result.orientation;
            area = R.result.area;
            mouseN = R.result.mouseN;
            colors = ['b', 'r', 'g', 'm'];
            subplot(3,1,1)
            for i=1:mouseN
                ecc1{i} = histfit(ori{i}(:,4),20,'gamma');
                ecc1{i}(1).FaceColor = [i./mouseN 0.8 1./i];
                ecc1{i}(2).Color = [1./i 0.2 i./mouseN];
                set(ecc1{i}(1),'facealpha',0.5)
                hold on
            end
            
            xlabel('Eccentricity','FontSize',16);
            ylabel('Distribution','FontSize',16);
            yt = get(gca, 'YTick');
            set(gca, 'YTick', yt, 'YTickLabel', round(100*yt/numel(ori{i}(:,4)))/100)
            set(gca,'fontsize',16,'linewidth',2,'box','on')
            hold off
            
            subplot(3,1,2)
            for i=1:mouseN
                h1{i} = histfit(area{i}(:,1),20,'Normal');
                h1{i}(1).FaceColor = [i./mouseN 0.8 1./i];
                h1{i}(2).Color = [1./i 0.2 i./mouseN];
                set(h1{i}(1),'facealpha',0.5)
                %n1{i} = sum(area{i}(:,1)<600);
                %text(200,400-i*50,['mouse' num2str(i) ' standing up: ' num2str(n1{i})]);
                hold on
            end
            xlabel('Mouse Area','FontSize',16);
            ylabel('Distribution','FontSize',16);
            set(gca,'fontsize',16,'linewidth',2,'box','on')
            hold off
            
            subplot(3,1,3)
            for i=1:mouseN
                majl1{i} = histfit(ori{i}(:,4),40,'Normal');
                majl1{i}(1).FaceColor = [i./mouseN 0.8 1./i];
                majl1{i}(2).Color = [1./i 0.2 i./mouseN];
                set(majl1{i}(1),'facealpha',0.5)
                hold on
            end
            xlabel('Major Axis Length','FontSize',16);
            ylabel('Distribution','FontSize',16);
            set(gca,'fontsize',16,'linewidth',2,'box','on')
            
            imgfilename1 = [app.dirpath app.video_name '/' app.video_name '_orientation'];
            
            % set(gcf, 'Units', 'Normalized', 'OuterPosition', [0, 0.04, 1, 0.96]);
            set(gcf, 'Position', [0, 0.04, 450, 900]);
            print(gcf,[imgfilename1 '.tif'],'-dtiff','-r300');
            
            hold off
            
            x = startframe:step:lastframe;
            ctimearray=zeros(length(x),1);
            for j=1:length(x)
                ctimearray(j) = (j-1).*step./frameRate;
            end
            
            xlsfilename = [app.video_name '_result.xls'];
            logf(app,'Saving Results into Excel and TIFF files, please wait...', [app.dirpath app.video_name '/' xlsfilename]);

            for i=1:mouseN
    
                arenaW = arena{i}(:,3);
                arenaH = arena{i}(:,4);
                pixels = 12.*25.4./arenaW; %pixel size in mm; the arena is 12 x 12 inches
                peripheral = floor(arenaW./12.*2); % 2 inches
                x1 = arena{i}(:,1) + peripheral;
                x2 = arena{i}(:,1) + arenaW - peripheral;
                y1 = arena{i}(:,2) + peripheral;
                y2 = arena{i}(:,2) + arenaH - peripheral;
                app.xa = [arena{i}(:,1),arena{i}(:,1)+arenaW,arena{i}(:,1)+arenaW,arena{i}(:,1),arena{i}(:,1)];
                app.ya = [arena{i}(:,2)+arenaH,arena{i}(:,2)+arenaH,arena{i}(:,2),arena{i}(:,2),arena{i}(:,2)+arenaH];
                xv = [x1 x2 x2 x1 x1];
                yv = [y2 y2 y1 y1 y2];
                
                mouse = ['mouse' num2str(i)];
                xq1 = pos{i}(:,1);
                yq1 = pos{i}(:,2);
                in1 = inpolygon(xq1,yq1,xv,yv);
                timesin1 = numel(xq1(in1));
                pathl1 =[0];
                dd1 = 0;
                distance1 = [0];
                for k = 2:length(pos{i})
                    D1 = sqrt((pos{i}(k,1)-pos{i}(k-1,1))^2+(pos{i}(k,2)-pos{i}(k-1,2))^2);
                    D1 = D1.* pixels; % path length in mm
                    pathl1 = [pathl1; D1];
                    dd1 = dd1 + D1./1000; % travel distance in m
                    distance1 = [distance1; dd1];
                end
                
                titlerow = horzcat({'Time (s)'},{[mouse '_X']},{[mouse '_Y']},{[mouse '_PathL (mm)']},{[mouse '_Distance(m)']});
                finaldata = horzcat(ctimearray,xq1,yq1,pathl1,distance1);
                
                TimeInner1 = timesin1.*step./ frameRate;
                TimeOuter1 = totalframe.*step./frameRate - TimeInner1;
                Thigmotaxis1 = 1-timesin1./length(x);
                summarytitle = [{'FileName'},{[mouse '_Outer (s)']},{[mouse '_Inner (s)']},{[mouse '_Thigmotaxis']}];
                summaryresults = [{[app.dirpath app.video_name '.mov']},TimeOuter1,TimeInner1,Thigmotaxis1];
                
                xlwrite([app.dirpath app.video_name '/' xlsfilename],titlerow,mouse,'A1');
                xlwrite([app.dirpath app.video_name '/' xlsfilename],finaldata,mouse,'A2');
                xlwrite([app.dirpath app.video_name '/' xlsfilename],summarytitle,mouse,'G1');
                xlwrite([app.dirpath app.video_name '/' xlsfilename],summaryresults,mouse,'G2');
                
                xv1{i} = xv;
                yv1{i} = yv;
                xa1{i} = app.xa;
                ya1{i} = app.ya;
                finaldata1{i} = finaldata;
                
            end
            hsum=figure('Visible','on');
            Bkg = 230 * ones(videosize(2), videosize(1), 3, 'uint8');
            
            subplot(1,2,1); image(Bkg); axis image
            %freezeColors;
            title([app.video_name '.mov'],'Interpreter','none');
            hold all
            %traveldistanceplot = [];
            for i=1:mouseN
                plot(pos{i}(:,1),pos{i}(:,2),'Color',colors(i));
                plot(xv1{i},yv1{i},'--k','LineWidth',2); % plot inner arena for each mouse
                plot(xa1{i},ya1{i},'k','LineWidth',2); % plot the entire arena
                %traveldistanceplot = [traveldistanceplot finaldata1{i}(:,5)];
            end
            set(gca,'fontsize',20)
            ymax = 0;
            
            subplot(1,2,2);
            for i=1:mouseN
                ymax = max(ymax, max(finaldata1{i}(:,5)));
                plot(finaldata1{1}(:,1),finaldata1{i}(:,5),'LineWidth',2);
                hold on
            end
            hold off
            
            xlim([0 length(x).*step./frameRate]);
            ylim([0 ymax]);
            xlabel('Times (s)');
            ylabel('Travel Distance (m)');
            set(gca,'linewidth',2,'fontsize',20,'box', 'off');
            set(gcf,'Units','Normalized','Position',[0 0 1 0.5],'PaperPositionMode','auto','PaperSize',[14 14]);
            title(sprintf(app.video_name));
            
            imgfilename = [app.dirpath app.video_name '/' app.video_name '_summary'];
            print(gcf,[imgfilename '.tif'],'-dtiff','-r300');
            hold off
            %delete(hsum);
            
            logf(app, 'Results saved to folder %s', [app.dirpath app.video_name '/']);
        end
        
        function logf(app, varargin)
            message = sprintf(varargin{1}, varargin{2:end});
            str = ['[' datestr(now(), 'HH:MM:SS') '] ' message];
            disp(str);
        end
        
        function [X,Y]=calculateEllipse(app,x, y, a, b, angle, steps)
            %     %# This functions returns points to draw an ellipse
            %     %#  @param x     X coordinate
            %     %#  @param y     Y coordinate
            %     %#  @param a     Semimajor axis
            %     %#  @param b     Semiminor axis
            %     %#  @param angle Angle of the ellipse (in degrees)
            
            narginchk(6, 7);
            if nargin<7, steps = 36; end
            
            beta = -angle * (pi / 180);
            sinbeta = sin(beta);
            cosbeta = cos(beta);
            
            alpha = linspace(0, 360, steps)' .* (pi / 180);
            sinalpha = sin(alpha);
            cosalpha = cos(alpha);
            
            X = x + (a * cosalpha * cosbeta - b * sinalpha * sinbeta);
            Y = y + (a * cosalpha * sinbeta + b * sinalpha * cosbeta);
            
            if nargout==1, X = [X Y]; end
            
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.MortgageCalculatorUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {456, 456};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {274, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            if isequal(app.filename, 0) || isequal(app.dirpath, 0)
                msgbox('The path should contain a valid file!', 'Open video', 'error');
                return;
            end
            app.CheckresultsButton.Enable = 'off';
            app.threshold = app.ThresholdSpinner.Value;
            app.objpixels = app.MinimalpixelsofobjEditField.Value;
            app.drawnextButton.Enable = 'on';
            app.mouseN = app.MousenumberEditField.Value;
            app.StartFrame = app.StartframeEditField.Value;
            app.LastFrame = app.LastframeEditField.Value;
            app.Step = app.StepEditField.Value;
            drawrect(app, app.StartFrame, app.mouseN, app.video_obj)
            app.drawnextButton.Enable = 'off';
            disp(app.dirpath);
            disp(app.video_name)
            if(~exist([app.dirpath '\' app.video_name],'dir'))
                mkdir([app.dirpath '\' app.video_name]);
            else
                rmdir([app.dirpath '\' app.video_name],'s');
                mkdir([app.dirpath '\' app.video_name]);
            end
            
            [m1_majl,m1_minl,m1_ori,m1_ecc,m1_area] = collect_trackdata(app, app.nframes,app.StartFrame,app.LastFrame,app.Step,app.mouseN,app.threshold, ...
                                                                        app.objpixels,app.video_obj,app.xa,app.ya,app.arena_w,app.arena_h,app.resultpath);
            if exist(app.resultpath, 'file') == 2
                %&& exist([app.dirpath app.video_name '/' [app.video_name '_result.xls']], 'file') ~= 2
                logf(app, 'Tracking has been done, running analysis...');
                analysis(app);
            end
            app.CheckresultsButton.Enable = 'on';
        end

        % Value changed function: ThresholdSpinner
        function ThresholdSpinnerValueChanged(app, event)
            value = app.ThresholdSpinner.Value;
            imshow(bwareaopen(im2bw(255 - app.frame100, value), 50) * 255, "Parent", app.UIAxes)
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            [app.filename, app.dirpath] = uigetfile('*.mov', "Open video");
            filepath = [app.dirpath app.filename];
            [u1, app.video_name, u2] = fileparts(filepath);
            app.VideopathEditField.Value = filepath;
            app.result_name = [app.video_name '_result.mat'];
            app.resultpath = [app.dirpath app.video_name '/' app.result_name];
            app.video_obj = openvideo(app, filepath);
            imshow(bwareaopen(im2bw(255 - app.frame100, 0.5),50) * 255,"Parent", app.UIAxes);
            
        end

        % Button pushed function: drawnextButton
        function drawnextButtonPushed(app, event)
            uiresume;
        end

        % Button pushed function: CheckresultsButton
        function CheckresultsButtonPushed(app, event)
            trackmovie_name = [app.video_name 'result.avi'];
            trackmoviepath = [app.dirpath '\' app.video_name '/' trackmovie_name];
            if exist(trackmoviepath, 'file') ~= 2
                close all;
                %video_obj = VideoReader(filepath);
                R = load(app.resultpath);
                MouseN = R.result.mouseN;
                startframe = R.result.StartFrame;
                lastframe = R.result.LastFrame;
                c1 = R.result.positions;
                ori = R.result.orientation;
                area = R.result.area;
                step = R.result.step;
                x = startframe:step:lastframe;
                
                F(min(length(x),100)) = struct('cdata',[],'colormap',[]);
                
                colors = ['b', 'r', 'g', 'm', 'y'];
                
                for i=1:min(length(x),100)
                    
                    vframe = read(app.video_obj,x(i));
                    image(vframe);
                    hold on
                    
                    for mn=1:MouseN
                        m1_majl{mn} = ori{mn}(:,1);
                        m1_minl{mn} = ori{mn}(:,2);
                        m1_ori{mn} = ori{mn}(:,3);
                        m1_area{mn} = area{mn}(:,1);
                        m1_ecc{mn} = ori{mn}(:,4);
                        p1 = calculateEllipse(app, c1{mn}(i,1), c1{mn}(i,2), m1_majl{mn}(i,1)./2, m1_minl{mn}(i,1)./2, m1_ori{mn}(i,1));
                        plot(p1(:,1), p1(:,2), ['.-' colors(mn)])
                        text(c1{mn}(i,1),c1{mn}(i,2),num2str(mn),'Color',colors(mn),'FontSize',20,'FontWeight','bold')
                    end
                    
                    title(['Frame ' num2str(x(i))])
                    hold off
                    F(i) = getframe;
                end
                
                v = VideoWriter(trackmoviepath);
                open(v);
                writeVideo(v,F);
                close(v);
                logf(app,'Video saved.');
            else
                h=implay(trackmoviepath);
                play(h.DataSource.Controls);
            end          
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MortgageCalculatorUIFigure = uifigure('Visible', 'off');
            app.MortgageCalculatorUIFigure.Name = 'Mortgage Calculator';
            app.MortgageCalculatorUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.MortgageCalculatorUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.AnaylysisParametersPanel = uipanel(app.LeftPanel);
            app.AnaylysisParametersPanel.Title = 'Anaylysis Parameters';
            app.MousenumberEditFieldLabel = uilabel(app.AnaylysisParametersPanel);
            app.MousenumberEditFieldLabel.Text = 'Mouse number';
            app.MousenumberEditField = uieditfield(app.AnaylysisParametersPanel, 'numeric');
            app.MousenumberEditField.Limits = [1 4];
            app.MousenumberEditField.ValueDisplayFormat = '%.0f';
            app.MousenumberEditField.Value = 4;
            app.LastframeEditFieldLabel = uilabel(app.AnaylysisParametersPanel);
            app.LastframeEditFieldLabel.Text = 'Last frame';
            app.LastframeEditField = uieditfield(app.AnaylysisParametersPanel, 'numeric');
            app.StartframeEditFieldLabel = uilabel(app.AnaylysisParametersPanel);
            app.StartframeEditFieldLabel.Text = 'Start frame';
            app.StartframeEditField = uieditfield(app.AnaylysisParametersPanel, 'numeric');
            app.StartframeEditField.Value = 1;
            app.StepEditFieldLabel = uilabel(app.AnaylysisParametersPanel);
            app.StepEditFieldLabel.Text = 'Step';
            app.StepEditField = uieditfield(app.AnaylysisParametersPanel, 'numeric');
            app.RunButton = uibutton(app.AnaylysisParametersPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run ';
            app.MinimalpixelsofobjLabel = uilabel(app.AnaylysisParametersPanel);
            app.MinimalpixelsofobjLabel.Text = 'Minimal pixels of obj.';
            app.MinimalpixelsofobjEditField = uieditfield(app.AnaylysisParametersPanel, 'numeric');
            app.MinimalpixelsofobjEditField.Limits = [0.001 100];
            app.MinimalpixelsofobjEditField.Value = 4;
            app.ThresholdSpinnerLabel = uilabel(app.AnaylysisParametersPanel);
            app.ThresholdSpinnerLabel.Text = 'Threshold';
            app.ThresholdSpinner = uispinner(app.AnaylysisParametersPanel);
            app.ThresholdSpinner.Step = 0.1;
            app.ThresholdSpinner.Limits = [0.2 0.8];
            app.ThresholdSpinner.ValueChangedFcn = createCallbackFcn(app, @ThresholdSpinnerValueChanged, true);
            app.ThresholdSpinner.Value = 0.5;
            app.LoadVideoPanel = uipanel(app.LeftPanel);
            app.LoadVideoPanel.Title = 'Load Video';
            app.VideopathEditFieldLabel = uilabel(app.LoadVideoPanel);
            app.VideopathEditFieldLabel.Text = 'Video path';
            app.VideopathEditField = uieditfield(app.LoadVideoPanel, 'text');
            app.LoadButton = uibutton(app.LoadVideoPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Frame 100 bw')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.drawnextButton = uibutton(app.RightPanel, 'push');
            app.drawnextButton.ButtonPushedFcn = createCallbackFcn(app, @drawnextButtonPushed, true);
            app.drawnextButton.Enable = 'off';
            app.drawnextButton.Tooltip = {'This is for test'};
            app.drawnextButton.Text = 'draw next';
            app.CheckresultsButton = uibutton(app.RightPanel, 'push');
            app.CheckresultsButton.ButtonPushedFcn = createCallbackFcn(app, @CheckresultsButtonPushed, true);
            app.CheckresultsButton.Enable = 'off';
            app.CheckresultsButton.Text = 'Check results';
            % Show the figure after all components are created
            app.MortgageCalculatorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MouseActivityGui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MortgageCalculatorUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MortgageCalculatorUIFigure)
        end
    end
end